classdef Colocalization_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Spinner                      matlab.ui.control.Spinner
        AnalyzeButton                matlab.ui.control.Button
        SetTGNThresholdButton        matlab.ui.control.Button
        EraseROIButton               matlab.ui.control.Button
        SelectROIButton              matlab.ui.control.Button
        ResetcontrastButton          matlab.ui.control.Button
        AdjustcontrastrangeButton    matlab.ui.control.Button
        DisplayedvideoTextArea       matlab.ui.control.TextArea
        DisplayedvideoTextAreaLabel  matlab.ui.control.Label
        DeleteAllButton              matlab.ui.control.Button
        DeleteSelectedButton         matlab.ui.control.Button
        AddButton                    matlab.ui.control.Button
        VideoSelectionListBox        matlab.ui.control.ListBox
        VideoSelectionListBoxLabel   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        allfiles % List of videos to analyze and the paths
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            %% Addition of localization data files to the TrackFilesListBox. The result is app.allfiles, which can be edited
            % with the addition and deletion of videos. 
            [filename,path] = uigetfile('*.*','All Files (*.*)',...
                'Select files','MultiSelect','on');
            drawnow;
            figure(app.UIFigure) % avoids UI to end up in background
            path = string(path) + string(filename); %final string with the path
            n_img = length(filename);
            %we classify each image by the last characters (_G, _TGN or _PM)
            for i = 1:n_img
                name = split(filename{i},'.');
                name = name{1};
                name = split(name,'_');
                g = name{end-1}; %cell of character vectors
                groups{i} = g;
            end
            groups = unique(groups); % sets of images of the same field of view
            files = cell(length(groups),3);
            for i = 1:n_img 
                name = split(filename{i},'.');
                name = name{1};
                name = split(name,'_');
                clas = string(name{end}); %cell of strings
                im = string(name{end-1});
                name = string(name(1:end-1));
                name = strjoin(name,'_');
                name = char(name);
                c = find(strcmp(groups, im));
                files{c,1} = name;
                if clas(1,1) == "G"
                    files{c,3} = path{i};
                elseif clas(1,1) == "TGN"
                    files{c,2} = path{i};
                elseif clas(1,1) == "PM"
                    files{c,4} = path{i};
                end
            end
            app.allfiles = [app.allfiles;files];
            %% Change list in the selection listbox
            app.VideoSelectionListBox.Items = app.allfiles(:,1);
            app.VideoSelectionListBox.Value = app.VideoSelectionListBox.Items{1};
            app.VideoSelectionListBox.Enable = 'on';
            app.DisplayedvideoTextArea.Enable = 'on';
            app.DisplayedvideoTextArea.Value = app.allfiles(1,1);
        end

        % Value changed function: VideoSelectionListBox
        function VideoSelectionListBoxValueChanged(app, event)
            app.DeleteSelectedButton.Enable = 'on';
            app.DeleteAllButton.Enable = 'on';
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            NFiles = sum(cellfun('size',app.allfiles(:,1),1));
            if NFiles == 1
                app.allfiles = {}; 
                app.VideoSelectionListBox.Items = app.allfiles;
            else
                idx = ismember(app.allfiles(:,1),app.VideoSelectionListBox.Value);
                app.allfiles = app.allfiles(idx==0,:);
                app.VideoSelectionListBox.Items = app.allfiles(:,1);
            end
            app.DeleteSelectedButton.Enable = 'off';
            app.DisplayedvideoTextArea.Enable = 'off';
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            app.allfiles = {};
            app.DeleteAllButton.Enable = 'off';
            %% Change list in the selection listbox
            app.VideoSelectionListBox.Items = app.allfiles;
            app.DisplayedvideoTextArea.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.VideoSelectionListBoxLabel = uilabel(app.UIFigure);
            app.VideoSelectionListBoxLabel.Text = 'Video Selection';
            app.VideoSelectionListBox = uilistbox(app.UIFigure);
            app.VideoSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @VideoSelectionListBoxValueChanged, true);
            app.VideoSelectionListBox.Enable = 'off';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteSelectedButton = uibutton(app.UIFigure, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Enable = 'off';
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.DeleteAllButton = uibutton(app.UIFigure, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Enable = 'off';
            app.DeleteAllButton.Text = 'Delete All';
            app.DisplayedvideoTextAreaLabel = uilabel(app.UIFigure);
            app.DisplayedvideoTextAreaLabel.Enable = 'off';
            app.DisplayedvideoTextAreaLabel.Text = 'Displayed video';
            app.DisplayedvideoTextArea = uitextarea(app.UIFigure);
            app.DisplayedvideoTextArea.Editable = 'off';
            app.DisplayedvideoTextArea.Enable = 'off';
            app.AdjustcontrastrangeButton = uibutton(app.UIFigure, 'push');
            app.AdjustcontrastrangeButton.Enable = 'off';
            app.AdjustcontrastrangeButton.Text = 'Adjust contrast range';
            app.ResetcontrastButton = uibutton(app.UIFigure, 'push');
            app.ResetcontrastButton.Enable = 'off';
            app.ResetcontrastButton.Text = 'Reset contrast';
            app.SelectROIButton = uibutton(app.UIFigure, 'push');
            app.SelectROIButton.Enable = 'off';
            app.SelectROIButton.Text = 'Select ROI';
            app.EraseROIButton = uibutton(app.UIFigure, 'push');
            app.EraseROIButton.Enable = 'off';
            app.EraseROIButton.Text = 'Erase ROI';
            app.SetTGNThresholdButton = uibutton(app.UIFigure, 'push');
            app.SetTGNThresholdButton.Enable = 'off';
            app.SetTGNThresholdButton.Text = 'Set TGN Threshold';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Colocalization_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end